classdef realTimePlotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        targetPanel             matlab.ui.container.Panel
        SpField                 matlab.ui.control.EditField
        setpointEditFieldLabel  matlab.ui.control.Label
        SpKnob                  matlab.ui.control.Knob
        PPanel                  matlab.ui.container.Panel
        KpField                 matlab.ui.control.EditField
        KpLabel                 matlab.ui.control.Label
        KpKnob                  matlab.ui.control.Knob
        IPanel                  matlab.ui.container.Panel
        KiField                 matlab.ui.control.EditField
        KiEditFieldLabel        matlab.ui.control.Label
        KiKnob                  matlab.ui.control.Knob
        DPanel                  matlab.ui.container.Panel
        KdField                 matlab.ui.control.EditField
        KdEditFieldLabel        matlab.ui.control.Label
        KdKnob                  matlab.ui.control.Knob
        sendButton              matlab.ui.control.Button
        stopButton              matlab.ui.control.Button
        startButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myLine
        t0
        myport
        tmr
        spLine
    end
    
    methods (Access = private)
        
        function readSerial(app)
            try
        while app.myport.NumBytesAvailable > 0
            line = readline(app.myport);
            angle = str2double(strtrim(line));
            if ~isnan(angle)
                addpoints(app.myLine, toc(app.t0), angle);
                drawnow limitrate;
            end
            end
        catch
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Color = [0.15 0.15 0.15];
            app.UIAxes.Color = [0.1 0.1 0.1];        % خلفية داكنة
            app.UIAxes.XColor = [0.8 0.8 0.8];       % محاور رمادية فاتحة
            app.UIAxes.YColor = [0.8 0.8 0.8];
            app.UIAxes.GridColor = [0.4 0.4 0.4];
            app.myLine = animatedline(app.UIAxes, 'Color', [0 1 0.5], 'LineWidth', 2);
            app.spLine = yline(app.UIAxes, app.SpKnob.Value, '--', 'Color', [1 0.3 0.3], 'LineWidth', 2);
            grid(app.UIAxes, 'on');
            app.UIAxes.YLim = [-90 90];
            app.t0 = tic;
        
            % القيم الابتدائية
            app.KpField.Value = '0.8';
            app.KiField.Value = '16.8';
            app.KdField.Value = '0.0105';
            app.SpField.Value = '0.0';

            app.SpKnob.Limits  = [-90 90];
            app.SpKnob.Value   = 0;
            app.KpKnob.Limits  = [0 20];   app.KpKnob.Value = 0.8;
            app.KiKnob.Limits  = [0 20];    app.KiKnob.Value = 16.8;
            app.KdKnob.Limits  = [0 0.1];    app.KdKnob.Value = 0.0105;

            app.startButton.BackgroundColor = [0.2 0.7 0.3];   % أخضر
            app.stopButton.BackgroundColor  = [0.8 0.2 0.2];   % أحمر
            app.sendButton.BackgroundColor  = [0.2 0.4 0.8];   % أزرق
            app.startButton.FontColor = [1 1 1];
            app.stopButton.FontColor  = [1 1 1];
            app.sendButton.FontColor  = [1 1 1];

            app.targetPanel.BackgroundColor = [0.2 0.2 0.2];
            app.PPanel.BackgroundColor      = [0.2 0.2 0.2];
            app.IPanel.BackgroundColor      = [0.2 0.2 0.2];
            app.DPanel.BackgroundColor      = [0.2 0.2 0.2];

            app.SpKnob.FontColor = [1 1 1];
            app.KpKnob.FontColor = [1 1 1];
            app.KiKnob.FontColor = [1 1 1];
            app.KdKnob.FontColor = [1 1 1];

      
            app.setpointEditFieldLabel.FontColor = [1 1 1];
            app.KpLabel.FontColor = [1 1 1];
            app.KiEditFieldLabel.FontColor = [1 1 1];
            app.KdEditFieldLabel.FontColor = [1 1 1];
        
            % فتح السيريال
            app.myport = serialport('COM10', 115200);
            configureTerminator(app.myport, "LF");
            pause(2);
            flush(app.myport);

            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            clearpoints(app.myLine);
            app.t0 = tic;
            app.tmr = timer('Period', 0.05, ...
                            'ExecutionMode', 'fixedRate', ...
                            'TimerFcn', @(~,~) readSerial(app));
            start(app.tmr);
        end

        % Button pushed function: sendButton
        function sendButtonPushed(app, event)
            writeline(app.myport, sprintf('P%.2f', str2double(app.KpField.Value)));
            writeline(app.myport, sprintf('I%.2f', str2double(app.KiField.Value)));
            writeline(app.myport, sprintf('D%.2f', str2double(app.KdField.Value)));
            writeline(app.myport, sprintf('S%.2f', str2double(app.SpField.Value)));
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            if ~isempty(app.tmr)
            stop(app.tmr);
            delete(app.tmr);
            app.tmr = [];
            end
        end

        % Value changing function: KdKnob
        function KdKnobValueChanging2(app, event)
            app.KdField.Value = num2str(event.Value, '%.2f');
            
        end

        % Value changing function: KiKnob
        function KiKnobValueChanging2(app, event)
            app.KiField.Value = num2str(event.Value, '%.1f');
            
        end

        % Value changing function: KpKnob
        function KpKnobValueChanging2(app, event)
            app.KpField.Value = num2str(event.Value, '%.1f');
        end

        % Value changing function: SpKnob
        function SpKnobValueChanging2(app, event)
            app.SpField.Value = num2str(event.Value, '%.1f');
            app.spLine.Value = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Response')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.sendButton = uibutton(app.UIFigure, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButtonPushed, true);
            app.sendButton.Text = 'send';
            app.DPanel = uipanel(app.UIFigure);
            app.DPanel.Title = 'D';
            app.KdKnob = uiknob(app.DPanel, 'continuous');
            app.KdKnob.ValueChangingFcn = createCallbackFcn(app, @KdKnobValueChanging2, true);
            app.KdEditFieldLabel = uilabel(app.DPanel);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdField = uieditfield(app.DPanel, 'text');
            app.IPanel = uipanel(app.UIFigure);
            app.IPanel.Title = 'I';
            app.KiKnob = uiknob(app.IPanel, 'continuous');
            app.KiKnob.ValueChangingFcn = createCallbackFcn(app, @KiKnobValueChanging2, true);
            app.KiEditFieldLabel = uilabel(app.IPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiField = uieditfield(app.IPanel, 'text');
            app.PPanel = uipanel(app.UIFigure);
            app.PPanel.Title = 'P';
            app.KpKnob = uiknob(app.PPanel, 'continuous');
            app.KpKnob.ValueChangingFcn = createCallbackFcn(app, @KpKnobValueChanging2, true);
            app.KpLabel = uilabel(app.PPanel);
            app.KpLabel.Text = 'Kp';
            app.KpField = uieditfield(app.PPanel, 'text');
            app.targetPanel = uipanel(app.UIFigure);
            app.targetPanel.Title = 'target';
            app.SpKnob = uiknob(app.targetPanel, 'continuous');
            app.SpKnob.ValueChangingFcn = createCallbackFcn(app, @SpKnobValueChanging2, true);
            app.setpointEditFieldLabel = uilabel(app.targetPanel);
            app.setpointEditFieldLabel.Text = 'setpoint';
            app.SpField = uieditfield(app.targetPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimePlotting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end